classdef dendritic_segmentation_Integration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectTIFButton            matlab.ui.control.Button
        tif_field                  matlab.ui.control.EditField
        RunButton                  matlab.ui.control.Button
        NucleiChannelSpinnerLabel  matlab.ui.control.Label
        NucleiChannelSpinner       matlab.ui.control.Spinner
        DCChannelSpinnerLabel      matlab.ui.control.Label
        DCChannelSpinner           matlab.ui.control.Spinner
        SelectCSVButton            matlab.ui.control.Button
        csv_field                  matlab.ui.control.EditField
        debug                      matlab.ui.control.CheckBox
        file_name                  matlab.ui.control.EditField
        ThresholdSliderLabel       matlab.ui.control.Label
        ThresholdSlider            matlab.ui.control.Slider
        CloseFiguresButton         matlab.ui.control.Button
        DisplayTIFChannelsButton   matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function FinalImage = tif_reader(app,FileTif)
            InfoImage = imfinfo(FileTif);
            mImage = InfoImage(1).Width;
            nImage = InfoImage(1).Height;
            NumberImages = length(InfoImage);   
            %storage for image layers
            FinalImage1 = zeros(nImage, mImage, NumberImages, 'uint16');
            tifLink = Tiff(FileTif, 'r');
            for i = 1:NumberImages
                tifLink.setDirectory(i);
                FinalImage1(:,:,i) = tifLink.read();
            end
            tifLink.close();
            FinalImage = FinalImage1;
        end
        
        
        function Selection_Image = selection_logical(app,filepath, debug)
            % Read .csv file from ImageJ interface
            Selection = csvread(filepath,1,0);
            X = Selection(:,1);
            Y = Selection(:,2);
            I = Selection(:,3);
            % Remove all selection outside image boundary          
            idx = (X <= 0) | (Y <= 0);
            X(idx) = [];
            Y(idx) = [];
            I(idx) = [];
            % Create sparse representation
            Selection_Image = sparse(X,Y,I);    
            % Rotate to true position
            Selection_Image = fliplr(Selection_Image);
            Selection_Image = rot90(Selection_Image);
            % Convert to Logical
            Selection_Image = ~logical(Selection_Image);
            if debug == 1
                figure('Name','ROI Mask'); imagesc(Selection_Image);
            end
        end
        
        
        function run(app)
            % Nindex - layer for Nuclei Information
            % DCindex - layer for Dendridic Information
            Nindex = app.NucleiChannelSpinner.Value;
            DCindex = app.DCChannelSpinner.Value;      
            SelectionPath = app.csv_field.Value;
            debug_mode = app.debug.Value;
            TifPath = string(app.tif_field.Value);
            File = app.file_name.Value;
            k_threshold = app.ThresholdSlider.Value;
            % requires ROI to be saved as .csv from Fiji
            try
                Selection = selection_logical(app,SelectionPath, debug_mode);
            catch
                f = uifigure;
                uialert(f, 'Selection CSV not found.', 'CSV not found!')
                return
            end
            try
                FinalImage = tif_reader(app,TifPath);
            catch
                f = uifigure;
                uialert(f, 'TIF not found or has improper number of channels.', 'TIF not found!')
                return
            end
            try
                % adjust size if ROI doesn't match image size
                Selection = Selection(1:size(FinalImage(:,:,Nindex),1),1:size(FinalImage(:,:,Nindex),2));
            catch
                %padd ones if size doesn't match and error is thrown
                Selection = padarray(Selection',[abs(size(FinalImage(:,:,Nindex),2)-size(Selection,2)) 2],1,'post')';
                Selection = padarray(Selection',[abs(size(FinalImage(:,:,Nindex),1)-size(Selection,1)) 1],1,'post')';
                
                Selection = Selection(1:size(FinalImage(:,:,Nindex),1),1:size(FinalImage(:,:,Nindex),2));
            end           
            
            %% Nuclei Centers
            
            % Segmentation of nuclei using DoG filter
            gaussian1 = fspecial('Gaussian',[25,1],5);
            gaussian2 = fspecial('Gaussian',[25,1],6);
            Nuclei = convn(convn((FinalImage(:,:,Nindex))',gaussian1,'same')',gaussian1,'same') - convn(convn((FinalImage(:,:,Nindex))',gaussian2,'same')',gaussian2,'same') ;
            
            if debug_mode == 1
                %   generate a 'in process' image
                figure('Name','Magnified Convoluted Nuclie Image');
                imagesc(Nuclei(1:400,700:1100)); axis off;
            end
            
            
            % Finding Centers of Nuclei
            
            Nuclei_Centers = imregionalmax(Nuclei);
            
            %   generate a 'in process' image
            se = strel('disk',3);
            NC_Display = imdilate(Nuclei_Centers,se);
            if debug_mode == 1
                figure('Name','Magnified imregionalmax'); imagesc(NC_Display(1:400,700:1100)); axis off;
            end
            
            % pull off items outside selection area
            Nuclei_Centers(~logical(full(Selection))) = 0;
            
            %   generate a 'in process' image
            se = strel('disk',3);
            NC_Display = imdilate(Nuclei_Centers,se);
            if debug_mode == 1
                figure('Name','Magnified imregionalmax Selection Removed'); imagesc(NC_Display(1:400,700:1100)); axis off;
            end
            
            % correction for imregionalmax
            f = FinalImage(:,:,Nindex);
            Nuclei_Centers(Nuclei < 0.05*median(f(:)) | f < 0.5*median(f(:))) = 0;
            
            %   Results images
            se = strel('disk',3);
            NC_Display = imdilate(Nuclei_Centers,se);
            if debug_mode == 1
                figure('Name','imregionalmax Selection Removed'); imagesc(NC_Display); axis off;
            end
                            
            if debug_mode == 1
                figure('Name','Nuclei Centers'); imagesc(NC_Display); axis off;
                figure('Name','Nucei Image'); imagesc(FinalImage(:,:,Nindex)); title("Nuclei Image"); 
                figure('Name','Nuclei Image after Convolution'); imagesc(Nuclei); title("Nuclei after Convolution"); 
                figure('Name','Nuclei Center Locations'); imagesc(NC_Display); title("Nuclei Center locations");
                figure('Name','Nuclei Centers over Nuclei Image'); imagesc(150*NC_Display + 0.1*double(FinalImage(:,:,Nindex))); title("Nuclei over Nuclei Image");
                figure('Name','High Contrast Nuclei Centers over Nuclei Image'); imshowpair(FinalImage(:,:,Nindex),NC_Display);
            end
            
            %% Filter dendridic cells
            
            %  gaussian3 = fspecial('Gaussian',15,2);
            gaussian3 = fspecial('disk',5);
            
            D_thresh = FinalImage(:,:,DCindex);
            D = reshape(D_thresh,1,size(D_thresh,1)*size(D_thresh,2));
            D_dub = double(D);
            D_std = std(D_dub);
            D_thresh(D_thresh < mean(D)+2*D_std) = 0;
            D_thresh(~Selection) = 0;
            
            % figure; imagesc(FinalImage(:,:,DCindex)); 
            % figure; imagesc(D_thresh);
             
            Dendridic = convn(D_thresh,gaussian3,'same');
            Dendridic = Dendridic/max(max(Dendridic));
             
            if debug_mode == 1
               figure('Name','Thresholded DC Channel'); imagesc(Dendridic);
            end
            
           % Threshold nuclei centers based on Dendridic Cells
                            
            Nuclei_Dendritic = Nuclei_Centers;
            %D = reshape(Dendridic,1,size(Dendridic,1)*size(Dendridic,2));
            tol = k_threshold; % input threshold
            D = Dendridic;
            D(Nuclei_Centers == 0) = 0;
            Nuclei_Dendritic(D<tol) = 0;
        
            
            se = strel('disk',3);
            ND_Display = imdilate(Nuclei_Dendritic,se);
        
            figure('Name','3 Channel DC Centers'); 
            X = zeros(size(ND_Display,1),size(ND_Display,2),3);
            X(:,:,3) = double(FinalImage(:,:,Nindex))/4095;
            X(:,:,2) = double(FinalImage(:,:,DCindex))/4095;
            X(:,:,1) = double(ND_Display);
            X1 = X(:,:,3);
            X1(~Selection) = 0;
            X(:,:,3) = X1;
            X2 = X(:,:,2);
            X2(~Selection) = 0;
            X(:,:,2) = X2;
            imagesc(X); title(strcat('Overlay original outside selection with Dendritic Cells with threshold ',num2str(tol))); axis off;
            
            %save variables for Stats processing
            k_threshold_name = strrep(num2str(k_threshold,2),'.','_'); %replace decimals with underscore for file acess
            save(strcat(File,'thresh',k_threshold_name),'Selection','Nuclei_Centers','Nuclei_Dendritic'); %saves ROI, Nuclie centers, and DC nuclie centers as a 3D binary array
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectTIFButton
        function select_tif(app, event)
            [File, Path] = uigetfile({'*.tif'}, 'File Selector');
            FileTif = strcat(Path,File);
            app.tif_field.Value = FileTif;
            app.file_name.Value = erase(File,'.tif');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
           run(app)
        end

        % Button pushed function: SelectCSVButton
        function select_csv(app, event)
            [File, Path] = uigetfile({'*.csv'}, 'File Selector');
            Filecsv = strcat(Path,File);
            app.csv_field.Value = Filecsv;
        end

        % Callback function
        function close_figures(app, event)
            close all;
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all;
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: DisplayTIFChannelsButton
        function DisplayTIFChannelsButtonPushed(app, event)

            TifPath = string(app.tif_field.Value);
            try
                figure('Name','Channel 1');
                tif_1 = imread(TifPath,1);
                imagesc(tif_1);
                figure('Name','Channel 2');
                tif_2 = imread(TifPath,2);
                imagesc(tif_2);
                figure('Name','Channel 3');
                tif_3 = imread(TifPath,3);
                imagesc(tif_3);
            catch
                f = uifigure;
                uialert(f, 'TIF not found or has improper number of channels.', 'TIF not found')
                return                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.SelectTIFButton = uibutton(app.UIFigure, 'push');
            app.SelectTIFButton.ButtonPushedFcn = createCallbackFcn(app, @select_tif, true);
            app.SelectTIFButton.Text = 'Select TIF';
            app.tif_field = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NucleiChannelSpinnerLabel = uilabel(app.UIFigure);
            app.NucleiChannelSpinnerLabel.Text = 'Nuclei Channel';
            app.NucleiChannelSpinner = uispinner(app.UIFigure);
            app.NucleiChannelSpinner.Limits = [1 3];
            app.NucleiChannelSpinner.Value = 1;
            app.DCChannelSpinnerLabel = uilabel(app.UIFigure);
            app.DCChannelSpinnerLabel.Text = 'DC Channel';
            app.DCChannelSpinner = uispinner(app.UIFigure);
            app.DCChannelSpinner.Limits = [1 3];
            app.DCChannelSpinner.Value = 2;
            app.SelectCSVButton = uibutton(app.UIFigure, 'push');
            app.SelectCSVButton.ButtonPushedFcn = createCallbackFcn(app, @select_csv, true);
            app.SelectCSVButton.Text = 'Select CSV';
            app.csv_field = uieditfield(app.UIFigure, 'text');
            app.debug = uicheckbox(app.UIFigure);
            app.debug.Text = 'debug';
            app.file_name = uieditfield(app.UIFigure, 'text');
            app.file_name.Editable = 'off';
            app.file_name.Visible = 'off';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ThresholdSlider.MinorTicks = [0.1 0.3 0.5 0.7 0.9];
            app.ThresholdSlider.Value = 0.2;
            app.CloseFiguresButton = uibutton(app.UIFigure, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Text = 'Close Figures';
            app.DisplayTIFChannelsButton = uibutton(app.UIFigure, 'push');
            app.DisplayTIFChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayTIFChannelsButtonPushed, true);
            app.DisplayTIFChannelsButton.Text = 'Display TIF Channels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dendritic_segmentation_Integration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end